classdef MYT_checkpoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TESTButton                 matlab.ui.control.Button
        SetButton                  matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ExperimentEditField        matlab.ui.control.EditField
        ExperimentEditFieldLabel   matlab.ui.control.Label
        SetButton_FL               matlab.ui.control.StateButton
        SetButton_BF               matlab.ui.control.StateButton
        FLDirectoryEditField       matlab.ui.control.EditField
        FLDirectoryEditFieldLabel  matlab.ui.control.Label
        BFDirectoryEditField       matlab.ui.control.EditField
        BFDirectoryEditFieldLabel  matlab.ui.control.Label
        MainFolderEditField        matlab.ui.control.EditField
        MainFolderEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Exp_Name % Unique string for group of movies
        MAIN_dir % Home directory for data analysis and checkpoint file.
        active_prp = {} % List of active properties (to save) 
    end

    
    methods (Access = private)
        function set_prp(app, prp, val)
            app.(prp) = val;
            if any(contains(app.active_prp, prp))
                disp(prp + " changed to " + app.(prp));
            else
                app.active_prp{end+1} = prp;
                disp(prp + " initialized as " + app.(prp));
            end
        end
        
        function S = save_prp(app)
            for i = 1:length(app.active_prp)
                p = app.active_prp(i);
                p = p{1};
                disp("Adding " + p + " to save state");
                S.(p) = app.(p);
            end
        end
        
        function load_prp(app, L)
            f_L = fields(L);
            for i = 1:length(f_L)
                f = f_L(i);
                f = f{1};
                disp("Loading " + f + " from state");
                val = L.(f);
                set_prp(app, f, val)
                %logic matching variables to fields in GUI
            end
            %if variable not in load state, should be overwritten
        end
        
        function MYT_reset(app)
            app.UIFigure.Visible = 'off';

            disp("RESET");

            MYT_checkpoint();

            close(app.UIFigure)
      
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ExperimentEditField
        function ExperimentEditFieldValueChanged(app, event)
            app.Exp_Name = app.ExperimentEditField.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            %Need to check if save path exists
            %S.MAIN_dir = app.MAIN_dir;
            %S.Exp_Name = app.Exp_Name;

            S = save_prp(app);

            save_path = fullfile(app.MAIN_dir,app.Exp_Name);

            disp("Saving to " + save_path);

            save(save_path, '-struct','S');
        end

        % Value changed function: MainFolderEditField
        function MainFolderEditFieldValueChanged(app, event)
            app.MAIN_dir = app.MainFolderEditField.Value;
            if exist("app.MAIN_dir", "dir")
                app.DirLoadedCheckBox.Value = 1;
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            %FtsA = '/Users/myepes/Xiao Lab Dropbox/Lab Members/Yepes_Martin/Projects/FtsA/20211117-FtsA_FastTracking';
            app.MAIN_dir = uigetdir(FtsA,'Choose main data folder');
            app.MainFolderEditField.Value = app.MAIN_dir;
            app.DirLoadedCheckBox.Value = 1;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %FtsA = '/Users/myepes/Xiao Lab Dropbox/Lab Members/Yepes_Martin/Projects/FtsA/20211117-FtsA_FastTracking';
            [file,path] = uigetfile([],'Choose checkpoint file');
            load_path = fullfile(path, file);
            disp("Loading from " + load_path);

            L = load(load_path);
            load_prp(app, L);
            %disp(L);

            if isfield(L,'Exp_Name')
                app.ExperimentEditField.Value = L.Exp_Name;
            end

            if isfield(L,'MAIN_dir')
                app.MainFolderEditField.Value = L.MAIN_dir;
            end
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            tst_path = "/Users/myepes/Xiao Lab Dropbox/Lab Members/Yepes_Martin/Projects/FtsA/20211117-FtsA_FastTracking";
            tst_name = "bee_borg";
            tst_name1 = "bee_boorg";

            save_path = fullfile(tst_path,tst_name);
            save_path1 = fullfile(tst_path,tst_name1);

            set_prp(app, 'Exp_Name', tst_name);
            app.ExperimentEditField.Value = tst_name;

            S = save_prp(app);

            save(save_path, '-struct','S');

            L = load(save_path);
            load_prp(app, L);

            set_prp(app, 'Exp_Name', tst_name1);
            app.ExperimentEditField.Value = tst_name1;

            set_prp(app,'MAIN_dir', tst_path);
            app.MainFolderEditField.Value = tst_path;

            S1 = save_prp(app);

            save(save_path1, '-struct','S1');

            MYT_reset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainFolderEditFieldLabel = uilabel(app.UIFigure);
            app.MainFolderEditFieldLabel.Text = 'Main Folder';
            app.MainFolderEditField = uieditfield(app.UIFigure, 'text');
            app.MainFolderEditField.ValueChangedFcn = createCallbackFcn(app, @MainFolderEditFieldValueChanged, true);
            app.BFDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.BFDirectoryEditFieldLabel.Text = 'BF Directory';
            app.BFDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.FLDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.FLDirectoryEditFieldLabel.Text = 'FL Directory';
            app.FLDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SetButton_BF = uibutton(app.UIFigure, 'state');
            app.SetButton_BF.Text = 'Set';
            app.SetButton_FL = uibutton(app.UIFigure, 'state');
            app.SetButton_FL.Text = 'Set';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYT_checkpoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end